classdef RAD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        MonitorsButtonGroup          matlab.ui.container.ButtonGroup
        Button1_2                    matlab.ui.control.ToggleButton
        Cage6Button                  matlab.ui.control.ToggleButton
        Cage7Button                  matlab.ui.control.ToggleButton
        Button4_2                    matlab.ui.control.ToggleButton
        Button4                      matlab.ui.control.ToggleButton
        Cage3Button                  matlab.ui.control.ToggleButton
        Cage2Button                  matlab.ui.control.ToggleButton
        Button1                      matlab.ui.control.ToggleButton
        RecordingStatusLabel         matlab.ui.control.Label
        PORTEditField                matlab.ui.control.EditField
        PORTEditFieldLabel           matlab.ui.control.Label
        table_id                     matlab.ui.control.Label
        ElapsedTimeEditField_2       matlab.ui.control.EditField
        ElapsedTimeEditField_2Label  matlab.ui.control.Label
        Lamp1                        matlab.ui.control.Lamp
        result_directory             matlab.ui.control.EditField
        text19_2                     matlab.ui.control.Label
        dir                          matlab.ui.control.Button
        text19                       matlab.ui.control.Label
        animal_id                    matlab.ui.control.EditField
        start                        matlab.ui.control.Button
    end

properties (Access = public)
    settings_path
    dirpath
    ps
    arduino
    sdata
    start_t % datetime format for csv filenames
    ack
    state
    timer
    stopped
    run
    state_timer_timeout
    state_timer
    resdir
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app)
          addpath(genpath(['.' filesep 'src']));
          app.ElapsedTimeEditField_2.Value = "0m 0s";
          app.figure1.Name = 'Rodent Activity Monitor GUI';
          app.start.Visible = 'off';
          app.start.Visible = 'on';
          app.stopped = true;
          rng('shuffle');
          tic;
        end

        % Button pushed function: dir
        function dir_Callback(app, event)
            if isempty(app.dirpath)
                app.dirpath = pwd;
            end
            user_selection = uigetdir(app.dirpath);
            if user_selection == 0
                user_selection = pwd;
            end
            app.result_directory.Value = user_selection;
            app.dirpath = app.result_directory.Value;
            app.result_directory.Tooltip = user_selection;
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)

              saveStatus(app,'gui_settings.mat');
              delete(app);
              
        end

        % Callback function: not associated with a component
        function SaveSettingsButtonPushed(app, event)
            
            if isempty(app.settings_path)
                app.settings_path = pwd;
            end
            [fn,pn,FilterIndex] = ...
                 uiputfile({'*.mat'},'Input data file',app.settings_path);
            if (pn ~= 0)
                app.settings_path = pn;
            else
                 return;
            end
            saveStatus(app,[pn filesep fn]);

        end

        % Callback function: not associated with a component
        function LoadSettingsButtonPushed(app, event)
           [fn,pn,FilterIndex]  = ...
                 uigetfile({'*.mat'},'Input data file',app.settings_path);
            if (pn ~= 0)
                app.settings_path = pn;
            else
                 return;
            end
            loadStatus(app,[pn filesep fn]);
            
        end

        % Callback function: not associated with a component
        function reward_topButtonPushed(app, event)
           vl = app.reward_duration_top.Value;
           write(app.arduino,['r' 0],"uint8");
           fprintf('%4.3f  %s\n',toc,'Top Track Reward Given Manually');
        end

        % Callback function: not associated with a component
        function dir2_Callback(app, event)
             if isempty(app.dirpath)
                app.dirpath2 = pwd;
            end
            user_selection = uigetdir(app.dirpath);
            if user_selection == 0
                user_selection = pwd;
            end
            app.result_directory2.Value = user_selection;
            app.dirpath2 = app.result_directory2.Value;
            app.result_directory2.Tooltip = user_selection;
        end

        % Callback function: not associated with a component
        function motion_toggle(app, event)
            if app.stopped == false
                value = app.MotionDetectorSwitch_2.Value;
                if value == "On"
                    app.Lamp1.Color = [0, 1, 0];
                    % tells arduino to resume top state machine updates
                    write(app.arduino,['a' 1 1],"uint8");
                else
                    app.Lamp1.Color = [1, 0, 0];
                    % tells arduino to pause top state machine
                    write(app.arduino,['a' 1 0],"uint8");
                end
            else
                app.MotionDetectorSwitch_2.Value = 'Off';
            end
        end

        % Callback function: not associated with a component
        function dbl_click_nano_list(app, event)
            app.table_id.Text = "CONNECTING...";
            item = event.InteractionInformation.Item;
            n = app.nano_list.Items{item};
            comstr = ['COM' num2str(n)];

            app.arduino = [];
            % create serial communication object on port COM
            app.arduino=serialport(comstr,115200);
            app.arduino.Timeout = 0.05;

            % start serial reads in background
            configureTerminator(app.arduino,"CR/LF");
            configureCallback(app.arduino,"terminator",@(src,evnt)read_serial(src,evnt,app))
        end

        % Button pushed function: start
        function start_btn(app, event)
            function localTimerFx(~,~)
                elapsed_sec = toc;
                mins = floor(elapsed_sec/60);
                secs = floor(mod(elapsed_sec, 60));
                app.ElapsedTimeEditField_2.Value = mins + "m " + secs + "s";
            end

            if app.stopped == true
                app.start_t = "_" + string(datetime);
                app.stopped = false;
                app.start.Text = "Stop";
                app.timer = timer;
                app.timer.ExecutionMode = 'fixedRate';
                app.timer.TimerFcn = @localTimerFx;
                app.timer.Period = 1; % runs every 1 seconds
                start(app.timer);
                app.MotionDetectorSwitch_2.Value = 'On';
                app.PORTEditField.Value = "0";
                app.Lamp1.Color = [0, 1, 0];
                % tell the arduino to unlock top state machine
                write(app.arduino,['a' 1 1],"uint8");
            else
                app.stopped = true;
                app.start.Text = "Start";
                app.MotionDetectorSwitch_2.Value = 'Off';
                app.Lamp1.Color = [1, 0, 0];
                stop(app.timer)
                app.ElapsedTimeEditField_2.Value = '0m 0s';
                % tell the arduino to lock top state machine
                write(app.arduino,['a' 1 0],"uint8");
            end
        end

        % Callback function: not associated with a component
        function motion_toggle_bot(app, event)
            % ignore button press if not recording yet
            if app.stopped2 == false
                value = app.MotionDetectorSwitch.Value;
                if value == "On"
                    app.Lamp2.Color = [0, 1, 0];
                    % tells arduino to resume top state machine updates
                    write(app.arduino,['a' 0 1],"uint8");
                else
                    app.Lamp2.Color = [1, 0, 0];
                    % tells arduino to pause top state machine
                    write(app.arduino,['a' 0 0],"uint8");
                end
            else
                app.MotionDetectorSwitch.Value = 'Off';
            end
        end

        % Callback function: not associated with a component
        function r_dt_change(app, event)
            v = app.reward_duration_top.Value;
            % tell nano the new top solenoid open duration
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 0 lo hi],"uint8");
        end

        % Callback function: not associated with a component
        function r_dt_change_bot(app, event)
            v = app.reward_duration_bottom.Value;
            % tell nano the new top solenoid open duration
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 1 lo hi],"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @start_btn, true);
            app.start.Enable = 'off';
            app.start.Text = 'Start';
            app.animal_id = uieditfield(app.figure1, 'text');
            app.animal_id.Placeholder = '<empty>';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Animal ID';
            app.dir = uibutton(app.figure1, 'push');
            app.dir.ButtonPushedFcn = createCallbackFcn(app, @dir_Callback, true);
            app.dir.Text = 'Select Results Directory';
            app.text19_2 = uilabel(app.figure1);
            app.text19_2.Text = 'Result Dir';
            app.result_directory = uieditfield(app.figure1, 'text');
            app.result_directory.Editable = 'off';
            app.result_directory.Value = '<empty>';
            app.Lamp1 = uilamp(app.figure1);
            app.ElapsedTimeEditField_2Label = uilabel(app.figure1);
            app.ElapsedTimeEditField_2Label.Text = 'Elapsed Time:';
            app.ElapsedTimeEditField_2 = uieditfield(app.figure1, 'text');
            app.ElapsedTimeEditField_2.Editable = 'off';
            app.ElapsedTimeEditField_2.Placeholder = '0m 0s';
            app.ElapsedTimeEditField_2.Value = '0m 0s';
            app.table_id = uilabel(app.figure1);
            app.table_id.Text = 'NO CONNECTION';
            app.PORTEditFieldLabel = uilabel(app.figure1);
            app.PORTEditFieldLabel.Text = 'PORT';
            app.PORTEditField = uieditfield(app.figure1, 'text');
            app.PORTEditField.InputType = 'digits';
            app.PORTEditField.Placeholder = 'e.g. 80';
            app.PORTEditField.Value = '0';
            app.RecordingStatusLabel = uilabel(app.figure1);
            app.RecordingStatusLabel.Text = {'Recording'; 'Status'};
            app.MonitorsButtonGroup = uibuttongroup(app.figure1);
            app.MonitorsButtonGroup.Title = 'Monitors';
            app.Button1 = uitogglebutton(app.MonitorsButtonGroup);
            app.Button1.Text = 'Cage #1';
            app.Button1.Value = true;
            app.Cage2Button = uitogglebutton(app.MonitorsButtonGroup);
            app.Cage2Button.Text = 'Cage #2';
            app.Cage3Button = uitogglebutton(app.MonitorsButtonGroup);
            app.Cage3Button.Text = 'Cage #3';
            app.Button4 = uitogglebutton(app.MonitorsButtonGroup);
            app.Button4.Text = 'Cage #4';
            app.Button4_2 = uitogglebutton(app.MonitorsButtonGroup);
            app.Button4_2.Text = 'Cage #8';
            app.Cage7Button = uitogglebutton(app.MonitorsButtonGroup);
            app.Cage7Button.Text = 'Cage #7';
            app.Cage6Button = uitogglebutton(app.MonitorsButtonGroup);
            app.Cage6Button.Text = 'Cage #6';
            app.Button1_2 = uitogglebutton(app.MonitorsButtonGroup);
            app.Button1_2.Text = 'Cage #5';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RAD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end