classdef ee < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        alarm_interval               matlab.ui.control.EditField
        AlarmIntervalminutesLabel    matlab.ui.control.Label
        mute_btn                     matlab.ui.control.Button
        open_close_btn_4             matlab.ui.control.Button
        open_close_btn_3             matlab.ui.control.Button
        LapsEditField_2              matlab.ui.control.EditField
        LapsEditField_2Label         matlab.ui.control.Label
        LapsEditField                matlab.ui.control.EditField
        LapsEditFieldLabel           matlab.ui.control.Label
        MotionDetectorSwitch         matlab.ui.control.Switch
        MotionDetectorSwitchLabel    matlab.ui.control.Label
        ElapsedTimeEditField_3       matlab.ui.control.EditField
        ElapsedTimeEditField_3Label  matlab.ui.control.Label
        Lamp2                        matlab.ui.control.Lamp
        start_2                      matlab.ui.control.Button
        table_id                     matlab.ui.control.Label
        dir_2                        matlab.ui.control.Button
        MotionDetectorSwitch_2       matlab.ui.control.Switch
        MotionDetectorSwitch_2Label  matlab.ui.control.Label
        ElapsedTimeEditField_2       matlab.ui.control.EditField
        ElapsedTimeEditField_2Label  matlab.ui.control.Label
        Gen_2                        matlab.ui.container.Panel
        open_close_btn_2             matlab.ui.control.Button
        reward_top_2                 matlab.ui.control.Button
        reward_duration_bottom       matlab.ui.control.NumericEditField
        ExperimentDurationsLabel_11  matlab.ui.control.Label
        result_directory2            matlab.ui.control.EditField
        text19_4                     matlab.ui.control.Label
        text19_3                     matlab.ui.control.Label
        animal_id_2                  matlab.ui.control.EditField
        nano_list                    matlab.ui.control.ListBox
        SelectUSBdeviceLabel         matlab.ui.control.Label
        Lamp1                        matlab.ui.control.Lamp
        result_directory             matlab.ui.control.EditField
        text19_2                     matlab.ui.control.Label
        Gen                          matlab.ui.container.Panel
        open_close_btn_1             matlab.ui.control.Button
        reward_top                   matlab.ui.control.Button
        reward_duration_top          matlab.ui.control.NumericEditField
        ExperimentDurationsLabel_2   matlab.ui.control.Label
        SaveSettingsButton           matlab.ui.control.Button
        LoadSettingsButton           matlab.ui.control.Button
        dir                          matlab.ui.control.Button
        text19                       matlab.ui.control.Label
        animal_id                    matlab.ui.control.EditField
        start                        matlab.ui.control.Button
    end

properties (Access = public)
    settings_path
    dirpath
    dirpath2
    ps
    alarms % whether to beep 1 minute before track events
    arduino
    sdata
    start_t % datetime format for csv filenames
    start_t2
    ack
    top_last_beep % last alarm time, prevents spamming
    bot_last_beep
    tic_offset % can't use tic for both timers...
    tic_offset_2
    state
    timer
    timer2
    stopped
    stopped2
    run
    state_timer_timeout
    state_timer
    resdir
    number_of_sequences
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app)

          % addpath(genpath(['.' filesep 'src' filesep 'common']));
          addpath(genpath(['.' filesep 'src']));
          loadStatus(app,'gui_settings.mat');
          app.ElapsedTimeEditField_2.Value = "0m 0s";
          app.ElapsedTimeEditField_3.Value = "0m 0s";

          app.figure1.Name = 'Enrichment Lap Detection';
          app.start.Visible = 'off';
          app.start.Visible = 'on';
          app.stopped = true;
          app.start_2.Visible = 'off';
          app.start_2.Visible = 'on';
          app.stopped2 = true;
          init_fx(app);

        end

        % Button pushed function: dir
        function dir_Callback(app, event)
            if isempty(app.dirpath)
                app.dirpath = pwd;
            end
            user_selection = uigetdir(app.dirpath);
            if user_selection == 0
                user_selection = pwd;
            end
            app.result_directory.Value = user_selection;
            app.dirpath = app.result_directory.Value;
            app.result_directory.Tooltip = user_selection;
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)

              saveStatus(app,'gui_settings.mat');
              delete(app);
              
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            
            if isempty(app.settings_path)
                app.settings_path = pwd;
            end
            [fn,pn,FilterIndex] = ...
                 uiputfile({'*.mat'},'Input data file',app.settings_path);
            if (pn ~= 0)
                app.settings_path = pn;
            else
                 return;
            end
            saveStatus(app,[pn filesep fn]);

        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
           [fn,pn,FilterIndex]  = ...
                 uigetfile({'*.mat'},'Input data file',app.settings_path);
            if (pn ~= 0)
                app.settings_path = pn;
            else
                 return;
            end
            loadStatus(app,[pn filesep fn]);
            
            % tell nano the new solenoid open durations
            v = app.reward_duration_top.Value;
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 0 lo hi],"uint8");
            v = app.reward_duration_bottom.Value;
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 1 lo hi],"uint8");
        end

        % Button pushed function: reward_top
        function reward_topButtonPushed(app, event)
           vl = app.reward_duration_top.Value;
           write(app.arduino,['r' 0],"uint8");
           fprintf('%4.3f  %s\n',toc-app.tic_offset,'Top Track Reward Given Manually');
        end

        % Button pushed function: reward_top_2
        function reward_bottomButtonPushed(app, event)
           vl = app.reward_duration_bottom.Value;
           write(app.arduino,['r' 1],"uint8");
           fprintf('%4.3f  %s\n',toc-app.tic_offset_2,'Bottom Track Reward Given Manually');
        end

        % Button pushed function: dir_2
        function dir2_Callback(app, event)
             if isempty(app.dirpath)
                app.dirpath2 = pwd;
            end
            user_selection = uigetdir(app.dirpath);
            if user_selection == 0
                user_selection = pwd;
            end
            app.result_directory2.Value = user_selection;
            app.dirpath2 = app.result_directory2.Value;
            app.result_directory2.Tooltip = user_selection;
        end

        % Value changed function: MotionDetectorSwitch_2
        function motion_toggle(app, event)
            if app.stopped == false
                value = app.MotionDetectorSwitch_2.Value;
                if value == "On"
                    app.Lamp1.Color = [0, 1, 0];
                    % tells arduino to resume top state machine updates
                    write(app.arduino,['a' 1 1],"uint8");
                else
                    app.Lamp1.Color = [1, 0, 0];
                    % tells arduino to pause top state machine
                    write(app.arduino,['a' 1 0],"uint8");
                end
            else
                app.MotionDetectorSwitch_2.Value = 'Off';
            end
        end

        % Double-clicked callback: nano_list
        function dbl_click_nano_list(app, event)
            app.table_id.Text = "CONNECTING...";
            item = event.InteractionInformation.Item;
            n = app.nano_list.Items{item};
            comstr = ['COM' num2str(n)];

            app.arduino = [];
            % create serial communication object on port COM
            app.arduino=serialport(comstr,115200);
            app.arduino.Timeout = 0.05;

            % start serial reads in background
            configureTerminator(app.arduino,"CR/LF");
            configureCallback(app.arduino,"terminator",@(src,evnt)read_serial(src,evnt,app))

            % delay the command or it will not be received by the arduino
            pause on
            pause(5)
            pause off
            % tell nano the solenoid open durations
            v = app.reward_duration_top.Value;
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 0 lo hi],"uint8");
            v = app.reward_duration_bottom.Value;
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 1 lo hi],"uint8");
        end

        % Button pushed function: start
        function start_btn(app, event)
            function localTimerFx(~,~)
                elapsed_sec = toc - app.tic_offset;
                mins = floor(elapsed_sec/60);
                secs = floor(mod(elapsed_sec, 60));
                app.ElapsedTimeEditField_2.Value = mins + "m " + secs + "s";
                % turn background of timer yellow for 1 minute before
                % the alarm interval, if alarms are not muted
                if app.alarms
                    is_one_min_prior = (str2double(app.alarm_interval.Value) - mod(mins, str2double(app.alarm_interval.Value)) == 1);
                    if is_one_min_prior
                        app.ElapsedTimeEditField_2.BackgroundColor = [1 1 0];
                        if toc > app.top_last_beep + 61
                            beep;
                            app.top_last_beep = toc;
                        end
                    else
                        app.ElapsedTimeEditField_2.BackgroundColor = [1 1 1];
                    end
                end
            end

            if app.stopped == true
                app.start_t = "_" + string(datetime);
                app.stopped = false;
                app.start.Text = "Stop";
                app.tic_offset = toc;
                app.timer = timer;
                app.timer.ExecutionMode = 'fixedRate';
                app.timer.TimerFcn = @localTimerFx;
                app.timer.Period = 1; % runs every 1 seconds
                start(app.timer);
                app.MotionDetectorSwitch_2.Value = 'On';
                app.LapsEditField.Value = "0";
                app.Lamp1.Color = [0, 1, 0];
                % tell the arduino to unlock top state machine
                write(app.arduino,['a' 1 1],"uint8");
            else
                app.stopped = true;
                app.start.Text = "Start";
                app.MotionDetectorSwitch_2.Value = 'Off';
                app.Lamp1.Color = [1, 0, 0];
                stop(app.timer)
                app.ElapsedTimeEditField_2.Value = '0m 0s';
                app.ElapsedTimeEditField_2.BackgroundColor = [1 1 1];
                % tell the arduino to lock top state machine
                write(app.arduino,['a' 1 0],"uint8");
            end
        end

        % Value changed function: MotionDetectorSwitch
        function motion_toggle_bot(app, event)
            % ignore button press if not recording yet
            if app.stopped2 == false
                value = app.MotionDetectorSwitch.Value;
                if value == "On"
                    app.Lamp2.Color = [0, 1, 0];
                    % tells arduino to resume top state machine updates
                    write(app.arduino,['a' 0 1],"uint8");
                else
                    app.Lamp2.Color = [1, 0, 0];
                    % tells arduino to pause top state machine
                    write(app.arduino,['a' 0 0],"uint8");
                end
            else
                app.MotionDetectorSwitch.Value = 'Off';
            end
        end

        % Button pushed function: start_2
        function start_btn2(app, event)
            function localTimerFx2(~,~)
                elapsed_sec = toc - app.tic_offset_2;
                mins = floor(elapsed_sec/60);
                secs = floor(mod(elapsed_sec, 60));
                app.ElapsedTimeEditField_3.Value = mins + "m " + secs + "s";
                if app.alarms
                    is_one_min_prior = (str2double(app.alarm_interval.Value) - mod(mins, str2double(app.alarm_interval.Value)) == 1);
                    if is_one_min_prior
                        app.ElapsedTimeEditField_3.BackgroundColor = [1 1 0];
                        if toc > app.bot_last_beep + 61
                            beep;
                            app.bot_last_beep = toc;
                        end
                    else
                        app.ElapsedTimeEditField_3.BackgroundColor = [1 1 1];
                    end
                end
            end

            if app.stopped2 == true
                app.start_t2 = "_" + string(datetime);
                app.stopped2 = false;
                app.start_2.Text = "Stop";
                app.tic_offset_2 = toc;
                app.timer2 = timer;
                app.timer2.ExecutionMode = 'fixedRate';
                app.timer2.TimerFcn = @localTimerFx2;
                app.timer2.Period = 1; % runs every 1 seconds
                start(app.timer2);
                app.MotionDetectorSwitch_2.Value = 'On';
                app.LapsEditField_2.Value = "0";
                app.Lamp2.Color = [0, 1, 0];
                % tell the arduino to unlock bottom state machine
                write(app.arduino,['a' 0 1],"uint8");
            else
                app.stopped2 = true;
                app.start_2.Text = "Start";
                stop(app.timer2)
                app.ElapsedTimeEditField_3.Value = '0m 0s';
                app.ElapsedTimeEditField_3.BackgroundColor = [1 1 1];
                app.MotionDetectorSwitch_2.Value = 'Off';
                app.Lamp2.Color = [1, 0, 0];
                % tell the arduino to lock bottom state machine
                write(app.arduino,['a' 0 0],"uint8");
            end
        end

        % Value changed function: reward_duration_top
        function r_dt_change(app, event)
            v = app.reward_duration_top.Value;
            % tell nano the new top solenoid open duration
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 0 lo hi],"uint8");
        end

        % Value changed function: reward_duration_bottom
        function r_dt_change_bot(app, event)
            v = app.reward_duration_bottom.Value;
            % tell nano the new top solenoid open duration
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 1 lo hi],"uint8");
        end

        % Button pushed function: open_close_btn_1
        function open_close_solenoid(app, event)
           vl = app.open_close_btn_1.Text;
           write(app.arduino,['s' 0],"uint8");
           if vl == "Open" 
               app.open_close_btn_1.Text = "Close";
           else
               app.open_close_btn_1.Text = "Open";
           end
           fprintf('%4.3f  %s\n',toc-app.tic_offset,'Top Track Solenoid Manually Openned');
        end

        % Button pushed function: open_close_btn_2
        function open_close_solenoid_2(app, event)
           vl = app.open_close_btn_2.Text;
           write(app.arduino,['s' 1],"uint8");
           if vl == "Open" 
               app.open_close_btn_2.Text = "Close";
           else
               app.open_close_btn_2.Text = "Open";
           end
           fprintf('%4.3f  %s\n',toc-app.tic_offset,'Top Track Solenoid Manually Openned');
        end

        % Button pushed function: open_close_btn_3
        function minus_lap(app, event)
            disp("last lap removed for top track!");
            pt1 = app.result_directory.Value;
            pt2 = app.animal_id.Value + app.start_t + ".csv";
            pt2 = strrep(pt2,":","_"); % remove ':' from datetime
            top_csv = fullfile(pt1, pt2);
            T = readtable(top_csv);
            writetable(T(1:end-1,1), top_csv, 'WriteVariableNames', 0)
            % decrement top track lap counter
            str_v = app.LapsEditField.Value;
            app.LapsEditField.Value = num2str(str2num(str_v) - 1);
        end

        % Button pushed function: open_close_btn_4
        function minus_lap_2(app, event)
            disp("last lap removed for bottom track!");
            pt1 = app.result_directory2.Value;
            pt2 = app.animal_id_2.Value + app.start_t2 + ".csv";
            pt2 = strrep(pt2,":","_"); % remove ':' from datetime
            bot_csv = fullfile(pt1, pt2);
            T = readtable(bot_csv);
            writetable(T(1:end-1,1), bot_csv, 'WriteVariableNames', 0)
            str_v = app.LapsEditField_2.Value;
            app.LapsEditField_2.Value = num2str(str2num(str_v) - 1);
        end

        % Button pushed function: mute_btn
        function toggle_alarms(app, event)
            % 🔇🔊
            if app.mute_btn.Text == "🔊"
                app.mute_btn.Text = "🔇";
                app.alarms = false;
            else
                app.mute_btn.Text = "🔊";
                app.alarms = true;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @start_btn, true);
            app.start.Enable = 'off';
            app.start.Tooltip = {'Start/Stop recording.'};
            app.start.Text = 'Start';
            app.animal_id = uieditfield(app.figure1, 'text');
            app.animal_id.Placeholder = '<empty>';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Top Animal ID';
            app.dir = uibutton(app.figure1, 'push');
            app.dir.ButtonPushedFcn = createCallbackFcn(app, @dir_Callback, true);
            app.dir.Tooltip = {'Choose where to save the output files.'};
            app.dir.Text = 'Select Results Directory';
            app.LoadSettingsButton = uibutton(app.figure1, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.figure1, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.Gen = uipanel(app.figure1);
            app.ExperimentDurationsLabel_2 = uilabel(app.Gen);
            app.ExperimentDurationsLabel_2.Text = {'Top Reward '; 'Duration (ms)'};
            app.reward_duration_top = uieditfield(app.Gen, 'numeric');
            app.reward_duration_top.ValueChangedFcn = createCallbackFcn(app, @r_dt_change, true);
            app.reward_duration_top.Value = 100;
            app.reward_top = uibutton(app.Gen, 'push');
            app.reward_top.ButtonPushedFcn = createCallbackFcn(app, @reward_topButtonPushed, true);
            app.reward_top.Tooltip = {'Manually Give Reward'};
            app.reward_top.Text = 'Reward';
            app.open_close_btn_1 = uibutton(app.Gen, 'push');
            app.open_close_btn_1.ButtonPushedFcn = createCallbackFcn(app, @open_close_solenoid, true);
            app.open_close_btn_1.Tooltip = {'Open/Close the reward solenoid.'};
            app.open_close_btn_1.Text = 'Open';
            app.text19_2 = uilabel(app.figure1);
            app.text19_2.Text = 'Result Dir';
            app.result_directory = uieditfield(app.figure1, 'text');
            app.result_directory.Editable = 'off';
            app.result_directory.Value = '<empty>';
            app.Lamp1 = uilamp(app.figure1);
            app.Lamp1.Tooltip = {'Status of motion sensors.'};
            app.SelectUSBdeviceLabel = uilabel(app.figure1);
            app.SelectUSBdeviceLabel.Text = {'Select USB device'; '(double click)'};
            app.nano_list = uilistbox(app.figure1);
            app.nano_list.Items = {'<empty>'};
            app.nano_list.Tooltip = {'Double click one of the USB-connected Arduinos to connect to it.'};
            app.nano_list.DoubleClickedFcn = createCallbackFcn(app, @dbl_click_nano_list, true);
            app.nano_list.Value = '<empty>';
            app.animal_id_2 = uieditfield(app.figure1, 'text');
            app.animal_id_2.Value = '<empty>';
            app.text19_3 = uilabel(app.figure1);
            app.text19_3.Text = 'Bottom Animal ID';
            app.text19_4 = uilabel(app.figure1);
            app.text19_4.Text = 'Result Dir';
            app.result_directory2 = uieditfield(app.figure1, 'text');
            app.result_directory2.Editable = 'off';
            app.result_directory2.Value = '<empty>';
            app.Gen_2 = uipanel(app.figure1);
            app.ExperimentDurationsLabel_11 = uilabel(app.Gen_2);
            app.ExperimentDurationsLabel_11.Text = {'Bottom Reward'; 'Duration (ms)'};
            app.reward_duration_bottom = uieditfield(app.Gen_2, 'numeric');
            app.reward_duration_bottom.ValueChangedFcn = createCallbackFcn(app, @r_dt_change_bot, true);
            app.reward_duration_bottom.Value = 110;
            app.reward_top_2 = uibutton(app.Gen_2, 'push');
            app.reward_top_2.ButtonPushedFcn = createCallbackFcn(app, @reward_bottomButtonPushed, true);
            app.reward_top_2.Tooltip = {'Manually Give Reward'};
            app.reward_top_2.Text = 'Reward';
            app.open_close_btn_2 = uibutton(app.Gen_2, 'push');
            app.open_close_btn_2.ButtonPushedFcn = createCallbackFcn(app, @open_close_solenoid_2, true);
            app.open_close_btn_2.Tooltip = {'Open/Close the reward solenoid.'};
            app.open_close_btn_2.Text = 'Open';
            app.ElapsedTimeEditField_2Label = uilabel(app.figure1);
            app.ElapsedTimeEditField_2Label.Text = 'Elapsed Time:';
            app.ElapsedTimeEditField_2 = uieditfield(app.figure1, 'text');
            app.ElapsedTimeEditField_2.Editable = 'off';
            app.ElapsedTimeEditField_2.Placeholder = '0m 0s';
            app.ElapsedTimeEditField_2.Value = '0m 0s';
            app.MotionDetectorSwitch_2Label = uilabel(app.figure1);
            app.MotionDetectorSwitch_2Label.Text = 'Motion Detector';
            app.MotionDetectorSwitch_2 = uiswitch(app.figure1, 'slider');
            app.MotionDetectorSwitch_2.ValueChangedFcn = createCallbackFcn(app, @motion_toggle, true);
            app.MotionDetectorSwitch_2.Tooltip = {'Switch On/Off the motion sensors.'};
            app.dir_2 = uibutton(app.figure1, 'push');
            app.dir_2.ButtonPushedFcn = createCallbackFcn(app, @dir2_Callback, true);
            app.dir_2.Tooltip = {'Choose where to save the output files.'};
            app.dir_2.Text = 'Select Results Directory';
            app.table_id = uilabel(app.figure1);
            app.table_id.Text = 'NO CONNECTION';
            app.start_2 = uibutton(app.figure1, 'push');
            app.start_2.ButtonPushedFcn = createCallbackFcn(app, @start_btn2, true);
            app.start_2.Enable = 'off';
            app.start_2.Tooltip = {'Start/Stop recording.'};
            app.start_2.Text = 'Start';
            app.Lamp2 = uilamp(app.figure1);
            app.Lamp2.Tooltip = {'Status of motion sensors.'};
            app.ElapsedTimeEditField_3Label = uilabel(app.figure1);
            app.ElapsedTimeEditField_3Label.Text = 'Elapsed Time:';
            app.ElapsedTimeEditField_3 = uieditfield(app.figure1, 'text');
            app.ElapsedTimeEditField_3.Editable = 'off';
            app.ElapsedTimeEditField_3.Placeholder = '0m 0s';
            app.ElapsedTimeEditField_3.Value = '0m 0s';
            app.MotionDetectorSwitchLabel = uilabel(app.figure1);
            app.MotionDetectorSwitchLabel.Text = 'Motion Detector';
            app.MotionDetectorSwitch = uiswitch(app.figure1, 'slider');
            app.MotionDetectorSwitch.ValueChangedFcn = createCallbackFcn(app, @motion_toggle_bot, true);
            app.MotionDetectorSwitch.Tooltip = {'Switch On/Off the motion sensors.'};
            app.LapsEditFieldLabel = uilabel(app.figure1);
            app.LapsEditFieldLabel.Text = 'Laps:';
            app.LapsEditField = uieditfield(app.figure1, 'text');
            app.LapsEditField.Editable = 'off';
            app.LapsEditField.Placeholder = '0';
            app.LapsEditField.Value = '0';
            app.LapsEditField_2Label = uilabel(app.figure1);
            app.LapsEditField_2Label.Text = 'Laps:';
            app.LapsEditField_2 = uieditfield(app.figure1, 'text');
            app.LapsEditField_2.Editable = 'off';
            app.LapsEditField_2.Placeholder = '0';
            app.LapsEditField_2.Value = '0';
            app.open_close_btn_3 = uibutton(app.figure1, 'push');
            app.open_close_btn_3.ButtonPushedFcn = createCallbackFcn(app, @minus_lap, true);
            app.open_close_btn_3.Tooltip = {'Remove the latest lap from the record.'};
            app.open_close_btn_3.Text = '-1';
            app.open_close_btn_4 = uibutton(app.figure1, 'push');
            app.open_close_btn_4.ButtonPushedFcn = createCallbackFcn(app, @minus_lap_2, true);
            app.open_close_btn_4.Tooltip = {'Remove the latest lap from the record.'};
            app.open_close_btn_4.Text = '-1';
            app.mute_btn = uibutton(app.figure1, 'push');
            app.mute_btn.ButtonPushedFcn = createCallbackFcn(app, @toggle_alarms, true);
            app.mute_btn.Tooltip = {'Toggle 1 minute alarm beeps'};
            app.mute_btn.Text = '🔊';
            app.AlarmIntervalminutesLabel = uilabel(app.figure1);
            app.AlarmIntervalminutesLabel.Text = {'Alarm Interval'; '(minutes)'};
            app.alarm_interval = uieditfield(app.figure1, 'text');
            app.alarm_interval.InputType = 'digits';
            app.alarm_interval.Placeholder = '1';
            app.alarm_interval.Value = '1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ee

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end